classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        ConfirmButton              matlab.ui.control.Button
        YearSliderLabel            matlab.ui.control.Label
        YearSlider                 matlab.ui.control.Slider
        GenderButtonGroup          matlab.ui.container.ButtonGroup
        MaleButton                 matlab.ui.control.RadioButton
        FemaleButton               matlab.ui.control.RadioButton
        CityButtonGroup            matlab.ui.container.ButtonGroup
        BostonButton               matlab.ui.control.ToggleButton
        BrocktonButton             matlab.ui.control.ToggleButton
        CambridgeButton            matlab.ui.control.ToggleButton
        LynnButton                 matlab.ui.control.ToggleButton
        SpringfieldButton          matlab.ui.control.ToggleButton
        PositionDropDownLabel      matlab.ui.control.Label
        PositionDropDown           matlab.ui.control.DropDown
        RaceEthnicKnobLabel        matlab.ui.control.Label
        RaceEthnicKnob             matlab.ui.control.DiscreteKnob
        RightPanel                 matlab.ui.container.Panel
        PredictedAnnualWageLabel   matlab.ui.control.Label
        PredictedMonthlyWageLabel  matlab.ui.control.Label
        EditField                  matlab.ui.control.NumericEditField
        EditField_2                matlab.ui.control.NumericEditField
        EditField_3Label_2         matlab.ui.control.Label
        EditField_3Label_3         matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: YearSlider
        function YearSliderValueChanged(app, event)
            year_ = app.YearSlider.Value;
            year_ = (year_ - 2016.5) / (sqrt(6)) % standardization
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            annual_wage = app.EditField.Value;
            annual_wage = 12 * monthly_wage - 96;
        end

        % Callback function
        function GenderButtonGroupSelectionChanged(app, event)
            gender = app.GenderButtonGroup.SelectedObject;
            
        end

        % Callback function
        function PositionDropDownValueChanged(app, event)
            position = app.PositionDropDown.Value;
            
        end

        % Callback function
        function CityButtonGroupSelectionChanged(app, event)
            city = app.CityButtonGroup.SelectedObject;
            
        end

        % Callback function
        function RaceEthnicKnobValueChanged(app, event)
            race = app.RaceEthnicKnob.Value;
        end

        % Callback function
        function EditField_2ValueChanged(app, event)
            monthly_wage = app.EditField_2.Value;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            year_ = (app.YearSlider.Value - 2016.5) / (sqrt(6)); % standardization
            gender = app.GenderButtonGroup.SelectedObject;
            position = app.PositionDropDown.Value;
            city = app.CityButtonGroup.SelectedObject;
            race = app.RaceEthnicKnob.Value;
            
            % initial input
            rotation = csvread('rotation.csv',1,1)
            rotation = rotation(:, 1:15)
            input = zeros(1, 19);
            
            % year
            input(1,1) = year_;
            
            % gender
            if gender == app.MaleButton
                input(1,2) = -1;
            elseif gender == app.FemaleButton
                input(1,2) = 1;
            end
            
            % city
            if city == app.BostonButton
                input(1,3) = 1;
                input(1,4) = 0;
                input(1,5) = 0;
                input(1,6) = 0;
                input(1,7) = 0;
            elseif city == app.BrocktonButton
                input(1,3) = 0;
                input(1,4) = 1;
                input(1,5) = 0;
                input(1,6) = 0;
                input(1,7) = 0;
            elseif city == app.CambridgeButton
                input(1,3) = 0;
                input(1,4) = 0;
                input(1,5) = 1;
                input(1,6) = 0;
                input(1,7) = 0;
            elseif city == app.LynnButton
                input(1,3) = 0;
                input(1,4) = 0;
                input(1,5) = 0;
                input(1,6) = 1;
                input(1,7) = 0;
            elseif city == app.SpringfieldButton
                input(1,3) = 0;
                input(1,4) = 0;
                input(1,5) = 0;
                input(1,6) = 0;
                input(1,7) = 1;
            end
            
            % position
            if position == "Officer"
                input(1,8) = 0;
                input(1,9) = 0;
                input(1,10) = 0;
                input(1,11) = 1;
                input(1,12) = 0;
                input(1,13) = 0;
                input(1,14) = 0;
                input(1,15) = 0;
            elseif position == "Detective"
                input(1,8) = 0;
                input(1,9) = 0;
                input(1,10) = 0;
                input(1,11) = 0;
                input(1,12) = 1;
                input(1,13) = 0;
                input(1,14) = 0;
                input(1,15) = 0;
            elseif position == "Sergeant"
                input(1,8) = 0;
                input(1,9) = 1;
                input(1,10) = 0;
                input(1,11) = 0;
                input(1,12) = 0;
                input(1,13) = 0;
                input(1,14) = 0;
                input(1,15) = 0;
            elseif position == "Captain"
                input(1,8) = 0;
                input(1,9) = 0;
                input(1,10) = 1;
                input(1,11) = 0;
                input(1,12) = 0;
                input(1,13) = 0;
                input(1,14) = 0;
                input(1,15) = 0;
            elseif position == "Lieutenant"
                input(1,8) = 1;
                input(1,9) = 0;
                input(1,10) = 0;
                input(1,11) = 0;
                input(1,12) = 0;
                input(1,13) = 0;
                input(1,14) = 0;
                input(1,15) = 0;
            elseif position == "Director"
                input(1,8) = 0;
                input(1,9) = 0;
                input(1,10) = 0;
                input(1,11) = 0;
                input(1,12) = 0;
                input(1,13) = 0;
                input(1,14) = 0;
                input(1,15) = 1;
            elseif position == "Commissioner"
                input(1,8) = 0;
                input(1,9) = 0;
                input(1,10) = 0;
                input(1,11) = 0;
                input(1,12) = 0;
                input(1,13) = 0;
                input(1,14) = 1;
                input(1,15) = 0;
            elseif position == "Others"
                input(1,8) = 0;
                input(1,9) = 0;
                input(1,10) = 0;
                input(1,11) = 0;
                input(1,12) = 0;
                input(1,13) = 1;
                input(1,14) = 0;
                input(1,15) = 0;
            end
            
            % race
            if race == "Asian"
                input(1,16) = 0;
                input(1,17) = 0;
                input(1,18) = 0;
                input(1,19) = 1;
            elseif race == "White"
                input(1,16) = 1;
                input(1,17) = 0;
                input(1,18) = 0;
                input(1,19) = 0;
            elseif race == "Black"
                input(1,16) = 0;
                input(1,17) = 1;
                input(1,18) = 0;
                input(1,19) = 0;
            elseif race == "Hispanic/Latin"
                input(1,16) = 0;
                input(1,17) = 0;
                input(1,18) = 1;
                input(1,19) = 0;
            end
            
            PC = input * rotation;
            weights = [-2533.130, -211.215, 44.628, 53.110, 1161.727, -422.439, 22.454, 18.651, 125.202, -29.083, -3.022, -260.594, -816.033, -399.871, 734.638];
            result = PC * transpose(weights);
            disp("res:")
            disp(result)
            monthly_wage = result(1) + 7975.508;
            annual_wage = 12 * monthly_wage - 96;
            app.EditField_2.Value = monthly_wage;
            app.EditField.Value = annual_wage;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {384, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ConfirmButton = uibutton(app.LeftPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.YearSliderLabel = uilabel(app.LeftPanel);
            app.YearSliderLabel.Text = 'Year';
            app.YearSlider = uislider(app.LeftPanel);
            app.YearSlider.Limits = [2015 2018];
            app.YearSlider.MajorTicks = [2015 2016 2017 2018];
            app.YearSlider.MajorTickLabels = {'2015', '2016', '2017', '2018'};
            app.YearSlider.ValueChangedFcn = createCallbackFcn(app, @YearSliderValueChanged, true);
            app.YearSlider.MinorTicks = [2015 2016 2017 2018];
            app.YearSlider.Value = 2015;
            app.GenderButtonGroup = uibuttongroup(app.LeftPanel);
            app.GenderButtonGroup.Title = 'Gender';
            app.MaleButton = uiradiobutton(app.GenderButtonGroup);
            app.MaleButton.Text = 'Male';
            app.MaleButton.Value = true;
            app.FemaleButton = uiradiobutton(app.GenderButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.CityButtonGroup = uibuttongroup(app.LeftPanel);
            app.CityButtonGroup.Title = 'City';
            app.BostonButton = uitogglebutton(app.CityButtonGroup);
            app.BostonButton.Text = 'Boston';
            app.BostonButton.Value = true;
            app.BrocktonButton = uitogglebutton(app.CityButtonGroup);
            app.BrocktonButton.Text = 'Brockton';
            app.CambridgeButton = uitogglebutton(app.CityButtonGroup);
            app.CambridgeButton.Text = 'Cambridge';
            app.LynnButton = uitogglebutton(app.CityButtonGroup);
            app.LynnButton.Text = 'Lynn';
            app.SpringfieldButton = uitogglebutton(app.CityButtonGroup);
            app.SpringfieldButton.Text = 'Springfield';
            app.PositionDropDownLabel = uilabel(app.LeftPanel);
            app.PositionDropDownLabel.Text = 'Position';
            app.PositionDropDown = uidropdown(app.LeftPanel);
            app.PositionDropDown.Items = {'Officer', 'Detective', 'Sergeant', 'Captain', 'Lieutenant', 'Director', 'Commissioner', 'Others'};
            app.PositionDropDown.Value = 'Officer';
            app.RaceEthnicKnobLabel = uilabel(app.LeftPanel);
            app.RaceEthnicKnobLabel.Text = 'Race/Ethnic';
            app.RaceEthnicKnob = uiknob(app.LeftPanel, 'discrete');
            app.RaceEthnicKnob.Items = {'Asian', 'White', 'Black', 'Hispanic/Latin'};
            app.RaceEthnicKnob.Value = 'Asian';
            app.RightPanel = uipanel(app.GridLayout);
            app.PredictedAnnualWageLabel = uilabel(app.RightPanel);
            app.PredictedAnnualWageLabel.Text = 'Predicted Annual Wage';
            app.PredictedMonthlyWageLabel = uilabel(app.RightPanel);
            app.PredictedMonthlyWageLabel.Text = 'Predicted Monthly Wage';
            app.EditField = uieditfield(app.RightPanel, 'numeric');
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%.0f';
            app.EditField_3Label_2 = uilabel(app.RightPanel);
            app.EditField_3Label_2.Text = '$';
            app.EditField_3Label_3 = uilabel(app.RightPanel);
            app.EditField_3Label_3.Text = '$';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end